classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ConsommationButtonGroup  matlab.ui.container.ButtonGroup
        MixteButton              matlab.ui.control.RadioButton
        ExoUrbaineButton         matlab.ui.control.RadioButton
        UrbaineButton            matlab.ui.control.RadioButton
        Marque1DropDown          matlab.ui.control.DropDown
        Marque1DropDownLabel     matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
        liste_marques
        marques
        liste_modele
        modele
        in_modele
        donnees
        num
        txt
        pas_ordonnes
        marque_modele
        type_donnees
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             % import des donnees
            Excel='mars-2014-complete.xlsx';
            app.num = readmatrix(Excel,"Sheet","mars-2014-complete");
            app.txt = readtable(Excel,"sheet","mars-2014-complete");


            app.marques = app.txt{2:end,1};
            app.modele = app.txt{2:end,3};
            app.liste_marques =app.modele;
  
            app.Marque1DropDown.Items = app.liste_marques;
            app.Marque1DropDown.ItemsData = 1:length(app.liste_marques);
         
        end

        % Value changed function: Marque1DropDown
        function Marque1DropDownValueChanged(app, event)
            value = app.Marque1DropDown.Value;
            app.donnees = app.num(2:end,:);
            app.in_modele=  app.Marque1DropDown.Value;
            app.type_donnees=10;
            bar(app.donnees(app.in_modele,app.type_donnees));

            app.pas_ordonnes=0:0.3:30;
            yticks(app.pas_ordonnes);
            set(gca,'FontName','Calibre')
            set(gca,'FontSize',8)
           
            marque_modele_legend=strcat(app.liste_marques{:,1});
            
            xticklabels(app.UIAxes,marque_modele_legend(app.in_modele))
            xlabel(app.UIAxes,'Marques,Modèles')
            ylbel(app.UIAxes,'Consomation Mixte')
   

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Differentes Consommations')
            xlabel(app.UIAxes, 'Model')
            ylabel(app.UIAxes, 'Consommation')
            zlabel(app.UIAxes, 'Z')
            app.Marque1DropDownLabel = uilabel(app.UIFigure);
            app.Marque1DropDownLabel.Text = 'Marque  1';
            app.Marque1DropDown = uidropdown(app.UIFigure);
            app.Marque1DropDown.Items = {'AUDI', 'MERCEDESE', 'Option 3', 'Option 4'};
            app.Marque1DropDown.ValueChangedFcn = createCallbackFcn(app, @Marque1DropDownValueChanged, true);
            app.Marque1DropDown.Value = 'AUDI';
            app.ConsommationButtonGroup = uibuttongroup(app.UIFigure);
            app.ConsommationButtonGroup.Title = 'Consommation ';
            app.UrbaineButton = uiradiobutton(app.ConsommationButtonGroup);
            app.UrbaineButton.Text = 'Urbaine';
            app.UrbaineButton.Value = true;
            app.ExoUrbaineButton = uiradiobutton(app.ConsommationButtonGroup);
            app.ExoUrbaineButton.Text = 'Exo Urbaine';
            app.MixteButton = uiradiobutton(app.ConsommationButtonGroup);
            app.MixteButton.Text = 'Mixte';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end