classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Modele4DropDown       matlab.ui.control.DropDown
        Modele4DropDownLabel  matlab.ui.control.Label
        Modele3DropDown       matlab.ui.control.DropDown
        Modele3DropDownLabel  matlab.ui.control.Label
        Modele1DropDown       matlab.ui.control.DropDown
        Modele1DropDownLabel  matlab.ui.control.Label
        Modele2DropDown       matlab.ui.control.DropDown
        Modele2DropDownLabel  matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
        liste_marques
        marques
        liste_modele
        modele
        in_modele
        donnees
        num
        txt
        pas_ordonnes
        marque_modele
        type_donnees% import des donnees
    end
    
    properties (Access = public)
        Property2 % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             Excel='mars-2014-complete.xlsx';
            app.num = readmatrix(Excel,"Sheet","mars-2014-complete");
            app.txt = readtable(Excel,"sheet","mars-2014-complete");


            app.marques = app.txt{2:end,1};
            app.modele = app.txt{2:end,3};
            app.liste_marques = strcat(app.marques," ",app.modele);
            %disp(app.liste_marques)
            app.Modele1DropDown.Items = app.liste_marques;
            app.Modele1DropDown.ItemsData = 1:length(app.liste_marques);
            app.Modele2DropDown.Items = app.liste_marques;
            app.Modele2DropDown.ItemsData = 1:length(app.liste_marques);
            app.Modele3DropDown.Items = app.liste_marques;
            app.Modele3DropDown.ItemsData = 1:length(app.liste_marques);
            app.Modele4DropDown.Items = app.liste_marques;
            app.Modele4DropDown.ItemsData = 1:length(app.liste_marques);
        end

        % Value changed function: Modele1DropDown
        function Modele1DropDownValueChanged(app, event)
            value = app.Modele1DropDown.Value;
            app.donnees = app.num(2:end,:);
            app.in_modele=  app.Modele1DropDown.Value;
            app.type_donnees=7;


            %% en dessous : dans tracerGraphe

            bar(app.UIAxes,app.donnees(app.in_modele,app.type_donnees));

            app.pas_ordonnes=0:50.3:1000;
            yticks(app.UIAxes, app.pas_ordonnes);
            %app.UIAxes.FontName='Calibre';
            %app.UIaxes.FontSize=8;
            set(gca,'FontName','Calibre')
            set(gca,'FontSize',8)
           
            marque_modele_legend=strcat(app.liste_marques{:,1});
            
            xticklabels(app.UIAxes,marque_modele_legend(app.in_modele))
            xlabel(app.UIAxes,'Marques,Modèles')
            ylabel(app.UIAxes,'Puissance moteur')
        end

        % Value changed function: Modele2DropDown
        function Modele2DropDownValueChanged(app, event)
            value = app.Modele2DropDown.Value;
            app.donnees = app.num(2:end,:);
            app.in_modele=[app.Modele1DropDown.Value app.Modele2DropDown.Value];
            app.type_donnees=7;


            %% en dessous : dans tracerGraphe

            bar(app.UIAxes,app.donnees(app.in_modele,app.type_donnees));

            app.pas_ordonnes=0:50.3:1000;
            yticks(app.UIAxes, app.pas_ordonnes);
            %app.UIAxes.FontName='Calibre';
            %app.UIaxes.FontSize=8;
            set(gca,'FontName','Calibre')
            set(gca,'FontSize',8)
           
            marque_modele_legend=strcat(app.liste_marques{:,1});
            
            xticklabels(app.UIAxes,marque_modele_legend(app.in_modele))
            xlabel(app.UIAxes,'Marques,Modèles')
            ylabel(app.UIAxes,'Puissance moteur')
        end

        % Value changed function: Modele3DropDown
        function Modele3DropDownValueChanged(app, event)
            value = app.Modele3DropDown.Value;
            app.donnees = app.num(2:end,:);
            app.in_modele= [app.Modele1DropDown.Value app.Modele2DropDown.Value app.Modele3DropDown.Value];
            app.type_donnees=7;


            %% en dessous : dans tracerGraphe

            bar(app.UIAxes,app.donnees(app.in_modele,app.type_donnees));

            app.pas_ordonnes=0:50.3:1000;
            yticks(app.UIAxes, app.pas_ordonnes);
            %app.UIAxes.FontName='Calibre';
            %app.UIaxes.FontSize=8;
            set(gca,'FontName','Calibre')
            set(gca,'FontSize',8)
           
            marque_modele_legend=strcat(app.liste_marques{:,1});
            
            xticklabels(app.UIAxes,marque_modele_legend(app.in_modele))
            xlabel(app.UIAxes,'Marques,Modèles')
            ylabel(app.UIAxes,'Puissance moteur')
        end

        % Value changed function: Modele4DropDown
        function Modele4DropDownValueChanged(app, event)
            value = app.Modele4DropDown.Value;
            app.donnees = app.num(2:end,:);
            app.in_modele=[app.Modele1DropDown.Value app.Modele2DropDown.Value app.Modele3DropDown.Value app.Modele4DropDown.Value];
            app.type_donnees=7;


            %% en dessous : dans tracerGraphe

            bar(app.UIAxes,app.donnees(app.in_modele,app.type_donnees));

            app.pas_ordonnes=0:50.3:1000;
            yticks(app.UIAxes, app.pas_ordonnes);
            %app.UIAxes.FontName='Calibre';
            %app.UIaxes.FontSize=8;
            set(gca,'FontName','Calibre')
            set(gca,'FontSize',8)
           
            marque_modele_legend=strcat(app.liste_marques{:,1});
            
            xticklabels(app.UIAxes,marque_modele_legend(app.in_modele))
            xlabel(app.UIAxes,'Marques,Modèles')
            ylabel(app.UIAxes,'Puissance moteur')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Puissance des Voitures ')
            xlabel(app.UIAxes, 'Marque')
            ylabel(app.UIAxes, 'Puissance')
            zlabel(app.UIAxes, 'Z')
            app.Modele2DropDownLabel = uilabel(app.UIFigure);
            app.Modele2DropDownLabel.Text = 'Modele 2';
            app.Modele2DropDown = uidropdown(app.UIFigure);
            app.Modele2DropDown.ValueChangedFcn = createCallbackFcn(app, @Modele2DropDownValueChanged, true);
            app.Modele1DropDownLabel = uilabel(app.UIFigure);
            app.Modele1DropDownLabel.Text = 'Modele 1';
            app.Modele1DropDown = uidropdown(app.UIFigure);
            app.Modele1DropDown.ValueChangedFcn = createCallbackFcn(app, @Modele1DropDownValueChanged, true);
            app.Modele3DropDownLabel = uilabel(app.UIFigure);
            app.Modele3DropDownLabel.Text = 'Modele 3';
            app.Modele3DropDown = uidropdown(app.UIFigure);
            app.Modele3DropDown.ValueChangedFcn = createCallbackFcn(app, @Modele3DropDownValueChanged, true);
            app.Modele4DropDownLabel = uilabel(app.UIFigure);
            app.Modele4DropDownLabel.Text = 'Modele 4';
            app.Modele4DropDown = uidropdown(app.UIFigure);
            app.Modele4DropDown.ValueChangedFcn = createCallbackFcn(app, @Modele4DropDownValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end